classdef prj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EqualizerEXUIFigure        matlab.ui.Figure
        BrowseButton               matlab.ui.control.Button
        FilePathEditFieldLabel     matlab.ui.control.Label
        FilePathEditField          matlab.ui.control.EditField
        TrackNameLabel             matlab.ui.control.Label
        SampleRateLabel            matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        Label                      matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        ArtistLabel                matlab.ui.control.Label
        VolumeSliderLabel          matlab.ui.control.Label
        VolumeSlider               matlab.ui.control.Slider
        PlayButton                 matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        EQUALIZERPanel             matlab.ui.container.Panel
        Slider_10                  matlab.ui.control.Slider
        Slider_9                   matlab.ui.control.Slider
        Slider_8                   matlab.ui.control.Slider
        Slider_7                   matlab.ui.control.Slider
        Slider_6                   matlab.ui.control.Slider
        Slider_5                   matlab.ui.control.Slider
        Slider_4                   matlab.ui.control.Slider
        Slider_3                   matlab.ui.control.Slider
        Slider_2                   matlab.ui.control.Slider
        Slider_1                   matlab.ui.control.Slider
        HzLabel                    matlab.ui.control.Label
        HzLabel_2                  matlab.ui.control.Label
        HzLabel_3                  matlab.ui.control.Label
        HzLabel_4                  matlab.ui.control.Label
        HzLabel_5                  matlab.ui.control.Label
        kHzLabel_2                 matlab.ui.control.Label
        kHzLabel_3                 matlab.ui.control.Label
        kHzLabel_4                 matlab.ui.control.Label
        kHzLabel_5                 matlab.ui.control.Label
        kHzLabel_6                 matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        PRESETSButtonGroup         matlab.ui.container.ButtonGroup
        ManualButton               matlab.ui.control.ToggleButton
        PopButton                  matlab.ui.control.ToggleButton
        RockButton                 matlab.ui.control.ToggleButton
        VocalButton                matlab.ui.control.ToggleButton
        TrebleButton               matlab.ui.control.ToggleButton
        JazzButton                 matlab.ui.control.ToggleButton
        FlatButton_2               matlab.ui.control.ToggleButton
        BassButton                 matlab.ui.control.ToggleButton
        LiveButton                 matlab.ui.control.ToggleButton
        ClubButton                 matlab.ui.control.ToggleButton
        PowerfulButton             matlab.ui.control.ToggleButton
        DanceButton                matlab.ui.control.ToggleButton
        SoftButton                 matlab.ui.control.ToggleButton
        PartyButton                matlab.ui.control.ToggleButton
        PerfectButton              matlab.ui.control.ToggleButton
        EargasmButton              matlab.ui.control.ToggleButton
        TabGroup                   matlab.ui.container.TabGroup
        FrequencySpectrumTab       matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        EqualizerFilterTab         matlab.ui.container.Tab
        UIAxes_2                   matlab.ui.control.UIAxes
        TimePlotTab                matlab.ui.container.Tab
        UIAxes_3                   matlab.ui.control.UIAxes
        MusicInfoTab               matlab.ui.container.Tab
        TrackNameLabel_2           matlab.ui.control.Label
        ArtistInformationLabel     matlab.ui.control.Label
        AlbumLabel                 matlab.ui.control.Label
        DiscLabel                  matlab.ui.control.Label
        GenreLabel                 matlab.ui.control.Label
        TrackLabel                 matlab.ui.control.Label
        YearLabel                  matlab.ui.control.Label
        DurationLabel              matlab.ui.control.Label
        FileSizeLabel              matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        PerksTab                   matlab.ui.container.Tab
        SpeedSliderLabel           matlab.ui.control.Label
        SpeedSlider                matlab.ui.control.Slider
        xLabel                     matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        LyricsTab                  matlab.ui.container.Tab
        lyrc                       matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        ShoworiginalresponseLabel  matlab.ui.control.Label
        Switch_2                   matlab.ui.control.Switch
        ShowfilteredresponseLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        player_seek % timer that controls everything
        fileReader % contains all samples
        deviceWriter % device samples are written to
        total_samples % total samples in the fileReader
        total_duration % total duration extracted from metadata
        cur_sample % current sample being played
        frameLength % samples picked per frame
        sampleRate % sample rate of audio extracted from metadata
        status % player status
        t_path = "tst" % temp prev image path
        %% Global Equalizer Filter
        order
        centerFreq
        bandwidth
        gains
        equalizer_A
        equalizer_B
        equalizer 
        %%
        %% Plot Handles
        p1
        p2
        p3
        p4
        p5
        %%
        PYTHON_SUPPORTED
        LYRICS_AVAL
        lyrics = ("") % song lyrics if separate lrc file available
        cur_lrc = 1
    end

    methods (Access = private)
    
        function player_bar(app)
            % Get Music Data
            musicData = app.fileReader();   
            musicData_o = musicData;
            % Adjust Gain/Volume
            musicData = (app.VolumeSlider.Value/100)*musicData;
            % Apply Equalizer Filter
            musicData = app.equalizer(musicData,app.equalizer_B,app.equalizer_A);
            % Write to Audio Device
            app.deviceWriter(musicData);
            % Update time and seek bar
            app.cur_sample = app.cur_sample + app.frameLength;
            cur_value = floor(app.cur_sample*ceil(app.total_duration)/app.total_samples);
            update_bar(app,cur_value,0);
            % Show Lyrics
            if app.LYRICS_AVAL
                app.lyrc.Text = app.lyrics(round(app.cur_sample*ceil(app.total_duration)/app.total_samples,1)*10);
            end
            % If playback finishes, auto stop timer
            if app.cur_sample>=app.total_samples
                stop(app.player_seek);
                % Release all handles
                release(app.fileReader);
                release(app.deviceWriter);
                update_bar(app,0,1);
                app.PlayButton.Enable = true;
                app.cur_sample = 1;
                % Resetup file handle with starting sample
                app.fileReader = dsp.AudioFileReader(app.FilePathEditField.Value,'SamplesPerFrame',app.frameLength);
                app.status = 0;
                % Clear Previous Plots
                clearpoints(app.p1);
                clearpoints(app.p2);
                clearpoints(app.p3);
                clearpoints(app.p4);
            end       
            % Show original response if requested
            if app.Switch_2.Value=="On" && app.TabGroup.SelectedTab==app.FrequencySpectrumTab
                [fig_fft_1, ~ , ~] = spectrogram(musicData_o(:,1),app.frameLength,[],[],app.sampleRate);
                [fig_fft_2, ~ , ~] = spectrogram(musicData_o(:,2),app.frameLength,[],[],app.sampleRate);                
                fig_fft = mat2gray(abs((fig_fft_1+fig_fft_2)/app.frameLength)); % average both channels for spectrum
                fig_fft(1) = 0;
                fig_fft(end) = 0;
                addpoints(app.p1,1:length(fig_fft),fig_fft);
                drawnow limitrate;
            else
                clearpoints(app.p1);
            end
            % Can we get time domain plot please (downsampled to ease
            % plotting load and get a general idea)
            if app.Switch_2.Value=="On" && app.TabGroup.SelectedTab==app.TimePlotTab
                musicData_d_1 = downsample(musicData_o(:,1),ceil(app.frameLength/300));
                musicData_d_2 = downsample(musicData_o(:,2),ceil(app.frameLength/300));
                musicData_d = rescale(musicData_d_1+musicData_d_2,-1,1);
                % Set first and last point to 2
                musicData_d(1) = 2;
                musicData_d(end) = 2;
                addpoints(app.p3,1:length(musicData_d),musicData_d);
                drawnow limitrate;
            else
                clearpoints(app.p3);
            end
            % Only Plot if switch enabled (saves GUI redrawing and computations)
            if app.Switch.Value=="On" && app.TabGroup.SelectedTab==app.FrequencySpectrumTab
            % Compute Left and Right channel SFFT
                [fig_fft_1, ~ , ~] = spectrogram(musicData(:,1),app.frameLength,[],[],app.sampleRate);
                [fig_fft_2, ~ , ~] = spectrogram(musicData(:,2),app.frameLength,[],[],app.sampleRate);                
                fig_fft = mat2gray(abs((fig_fft_1+fig_fft_2)/app.frameLength)); % average both channels for spectrum
                fig_fft(1) = 0;
                fig_fft(end) = 0;
                addpoints(app.p2,1:length(fig_fft),fig_fft);
                drawnow limitrate;
            else
                clearpoints(app.p2);
            end
            if app.Switch.Value=="On" && app.TabGroup.SelectedTab==app.TimePlotTab
                musicData_d_1 = downsample(musicData(:,1),ceil(app.frameLength/300));
                musicData_d_2 = downsample(musicData(:,2),ceil(app.frameLength/300));
                musicData_d = rescale(musicData_d_1+musicData_d_2,-1,1);
                % Set first and last point to 2
                musicData_d(1) = 2;
                musicData_d(end) = 2;
                addpoints(app.p4,1:length(musicData_d),musicData_d);
                drawnow limitrate;
            else
                clearpoints(app.p4);
            end
            
        end
        
        function update_bar(app,val,rst)
            if (rst == 1)
                val = 0;
            end
            app.Slider.Value = val;
            dur_minutes = floor(val/60);
            dur_seconds = floor(val-dur_minutes*60);
            app.Label.Text = strcat(num2str(dur_minutes,'%02.f'),':',num2str(dur_seconds,'%02.f'));
        end


        function slider_ed(app,val)
            app.Slider_10.Enable = val;
            app.Slider_9.Enable = val;
            app.Slider_8.Enable = val;
            app.Slider_7.Enable = val;
            app.Slider_6.Enable = val;
            app.Slider_5.Enable = val;
            app.Slider_4.Enable = val;
            app.Slider_3.Enable = val;
            app.Slider_2.Enable = val;
            app.Slider_1.Enable = val;
        end
        
        function slider_val(app,val)
            app.Slider_1.Value = val(1);
            app.Slider_2.Value = val(2);
            app.Slider_3.Value = val(3);
            app.Slider_4.Value = val(4);
            app.Slider_5.Value = val(5);
            app.Slider_6.Value = val(6);
            app.Slider_7.Value = val(7);
            app.Slider_8.Value = val(8);
            app.Slider_9.Value = val(9);
            app.Slider_10.Value = val(10);
            
        end


        function python_trackinfo(app)
            % Can we get cover picture too? (requires python3 or above
            % installed system wide)
            command = strcat("python ext.py",{' '},'"',app.FilePathEditField.Value,'"');
            system(command);
            dt=load('info.mat');
            app.Label_7.Text = extractfield(dt,'album');
            app.Label_8.Text = extractfield(dt,'disc');
            app.Label_9.Text = extractfield(dt,'genre');
            app.Label_10.Text = extractfield(dt,'track');
            app.Label_11.Text = extractfield(dt,'year');
            app.t_path = strcat("'",extractfield(dt,'tt'),"'",'.jpg');
            app.Image.ImageSource = app.t_path;
            dt=dir(app.FilePathEditField.Value);
            app.Label_13.Text = strcat(num2str(extractfield(dt,'bytes')/1e6),{' '},'MB');
        end
        
        function populate_lyrics(app)
            path = app.FilePathEditField.Value;
            % Reset previous lyrics
            app.lyrics = ("");
            % Construct lrc path
            if path(end-3:end)==".mp3"
                path = path(1:end-4)+".lrc";
            elseif path(end-4:end)==".flac"
                path = path(1:end-5)+".lrc";
            elseif path(end-3:end)==".wav"
                path = path(1:end-4)+".lrc";
            end
            if isfile(path)
                % File exists (we don't check if file is valid or
                % something. Just load and process)
                file = fopen(path,'r','n','UTF-8');
                app.LYRICS_AVAL = 1;
                sec_i = 0;
                app.lyrc.Text = "";
                line = "...";
                while true
                   line_n = fgetl(file);
                   if ischar(line_n)
                       time = line_n(2:strfind(line_n,']')-1);
                       min = str2double(time(1:strfind(time,':')-1));
                       sec = str2double(time(strfind(time,':')+1:end));
                       sec = round(sec,1)+min*60;
                       sec = sec*10;
                       % Repeat the line sec times
                       line = repmat(line,sec-sec_i,1)';
                       app.lyrics = [app.lyrics,line];
                       sec_i = sec;
                       line = line_n;
                   else
                       sec = ceil(app.total_duration)*10;
                       line = strrep(line,"["+time+"]","");
                       line = repmat(line,sec-sec_i,1)';
                       app.lyrics = [app.lyrics,line];
                       break;
                   end
                       tr = line(1:strfind(line,']'));
                       line = native2unicode(strrep(line,tr,""),'UTF-8');
                end
                fclose(file);
            else
                % Since LRC doesn't exists, breakup
                app.lyrc.Text = "No .lrc file was found. Lyrics can't be loaded up. Please check if the file exists";
                app.LYRICS_AVAL = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.player_seek = timer;
            app.player_seek.Period = 0.1; % Address call delays
            app.player_seek.TasksToExecute = Inf;
            app.player_seek.ExecutionMode = 'fixedRate';
            app.player_seek.TimerFcn = @(~, ~)player_bar(app);
            % Startup Properties
            app.UIAxes.YLim = [0 1.01];
            app.UIAxes_2.YLim = [-24 24];
            app.UIAxes_2.XLim = [0 pi];
            app.UIAxes_3.XLim = [0 300];
            app.UIAxes_3.YLim = [-1 1];
            % Setup Equalizer
            app.order = 10*ones(1,10);
            app.centerFreq = [31.5 63 125 250 500 1000 2000 4000 8000 16000]/(44.1e3/2); 
            app.bandwidth = [22 44 89 178 355 710 1420 2840 5680 11360]/(44.1e3/2);
            app.gains = [0 0 0 0 0 0 0 0 0 0];
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            app.equalizer = dsp.BiquadFilter(...
                'SOSMatrixSource','Input port',...
                'ScaleValuesInputPort',false);
            % Load spectrogram and plot into memory (pure garbage) to
            % reduce computational load on first call
            [~] = spectrogram(zeros(1,10));
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,w] = freqz(SOS);
            app.p5 = plot(app.UIAxes_2,w,20*log10(abs(H)),'Color',[186 85 54]/255);
            % Setup Plot handles
            app.p3 = animatedline(app.UIAxes_3,'MaximumNumPoints',300,'Color',[51 107 135]/255);
            app.p4 = animatedline(app.UIAxes_3,'MaximumNumPoints',300,'Color',[137 218 89]/255);
            % Check if Python is available (doesn't checks if modules are
            % present
            [app.PYTHON_SUPPORTED] = system('python -h');
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.flac;*.mp3;','Audio files (*.wav,*.flac,*.mp3)'},'Select an audio file to play');
            figure(app.EqualizerEXUIFigure);
            if file==0
                return;
            end
            delete(app.Image);
            app.Image = uiimage(app.MusicInfoTab);
            app.Image.Position = [47 55 241 226]; 
            delete(app.t_path);
            % Load metadata
            app.FilePathEditField.Value = strcat(path,file);
            metadata = audioinfo(app.FilePathEditField.Value);
            % Check if Artist and Title exist and update accordingly
            title = char(extractfield(metadata,'Title'));
            if title==""
                title = "Unknown Track";
            end
            artist = char(extractfield(metadata,'Artist'));
            if artist==""
                artist = "Unknown Artist";
            end
            % Load Metadata from Python
            if ~app.PYTHON_SUPPORTED
                python_trackinfo(app)
            end
            % Load technical music stuff and update
            app.total_duration = extractfield(metadata,'Duration');
            app.sampleRate = extractfield(metadata,'SampleRate');
            % Set maximum playback time
            dur_minutes = floor(app.total_duration/60);
            dur_seconds = ceil(app.total_duration-dur_minutes*60);
            % Fill Up Music Info
            app.TrackNameLabel.Text = title;
            app.ArtistLabel.Text = artist;
            app.TrackNameLabel_2.Text = title;
            app.ArtistInformationLabel.Text = artist;
            app.Label_3.Text = strcat(num2str(dur_minutes,'%02.f'),':',num2str(dur_seconds,'%02.f'));
            app.Slider.Limits = [0 ceil(app.total_duration)];
            app.Label_12.Text = strcat(num2str(dur_minutes,'%02.f'),{' '},'minutes',{' '},num2str(dur_seconds,'%02.f'),{' '},'seconds');
            app.SampleRateLabel.Text = strcat(num2str(app.sampleRate),{' Hz'});
            % Extract Lyrics
            populate_lyrics(app);
            app.cur_lrc = 1;
            % Setup Player properties
            app.frameLength = ceil(0.1*app.SpeedSlider.Value*app.sampleRate);
            app.fileReader = dsp.AudioFileReader(app.FilePathEditField.Value,'SamplesPerFrame',app.frameLength);
            app.deviceWriter = audioDeviceWriter('SampleRate',ceil(app.SpeedSlider.Value*app.sampleRate),'SupportVariableSizeInput',true);
            app.p1 = animatedline(app.UIAxes,'MaximumNumPoints',0.1*app.sampleRate,'Color',[51 107 135]/255);
            app.p2 = animatedline(app.UIAxes,'MaximumNumPoints',0.1*app.sampleRate,'Color',[137 218 89]/255);
            % Clear Previous Plots
            clearpoints(app.p1);
            clearpoints(app.p2);
            clearpoints(app.p3);
            clearpoints(app.p4);
            % Setup defaults
            app.total_samples = ceil(app.sampleRate*app.total_duration);
            app.cur_sample = 1;
            update_bar(app,app.cur_sample,1);
            % Set frequency axis according to Sample Rate
            app.UIAxes.XLim = [0 0.1*app.sampleRate];
            % Reduce Computational Load
            setup(app.deviceWriter,ones(app.frameLength,2));
            setup(app.fileReader);
            % Enable buttons
            app.PlayButton.Enable = true;
            app.PauseButton.Enable = true;
            app.StopButton.Enable = true;
            app.SpeedSlider.Enable = true;
            app.Slider.Enable = true;            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            start(app.player_seek);
            app.PlayButton.Enable = false;
            app.PauseButton.Enable = true;
            app.status = 1;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            stop(app.player_seek);
            app.PlayButton.Enable = true;
            app.PauseButton.Enable = false;
            app.status = 0;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player_seek);
            % Release all handles
            release(app.fileReader);
            release(app.deviceWriter);
            % Reset samples
            app.cur_sample = 1;
            update_bar(app,0,1);
            app.PlayButton.Enable = true;
            app.PauseButton.Enable = false;
            % Resetup file handler
            app.fileReader = dsp.AudioFileReader(app.FilePathEditField.Value,'SamplesPerFrame',app.frameLength);
            app.status = 0;
            app.cur_lrc = 1;
            app.lyrc.Text = "";
            % Clear Previous Plots
            clearpoints(app.p1);
            clearpoints(app.p2);
            clearpoints(app.p3);
            clearpoints(app.p4);
        end

        % Value changing function: Slider_1
        function Slider_1ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(1) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(2) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(6) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(3) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(4) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(5) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_7
        function Slider_7ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(8) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_9
        function Slider_9ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(9) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_8
        function Slider_8ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(7) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: Slider_10
        function Slider_10ValueChanging(app, event)
            changingValue = event.Value;
            app.gains(10) = changingValue;
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Selection changed function: PRESETSButtonGroup
        function PRESETSButtonGroupSelectionChanged(app, event)
            selectedButton = app.PRESETSButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Manual"
                    slider_ed(app,true);
                case "Flat"
                    slider_val(app,[0 0 0 0 0 0 0 0 0 0]);
                    app.gains = [0 0 0 0 0 0 0 0 0 0];
                    slider_ed(app,false);
                case "Pop"
                    slider_val(app,[-2 2 4 5.8 4 -2 -3.5 -3.5 -3.5 -3.5]);
                    app.gains = [-2 2 4 5.8 4 -2 -3.5 -3.5 -3.5 -3.5];
                    slider_ed(app,false);
                case "Rock"
                    slider_val(app,[4 2 -2.5 -5.3 -2.5 2.5 5.3 6.5 6.5 6.5]);
                    app.gains = [4 2 -2.5 -5.3 -2.5 2.5 5.3 6.5 6.5 6.5];
                    slider_ed(app,false);
                case "Vocal"
                    slider_val(app,[-0.7 0.1 -4.6 2.4 4.6 5 2.8 2.6 0 -2]);
                    app.gains = [-0.7 0.1 -4.6 2.4 4.6 5 2.8 2.6 0 -2];
                    slider_ed(app,false);
                case "Treble"
                    slider_val(app,[-8 -8 -8 -4.5 0 3.2 9 9 9 9]);
                    app.gains = [-8 -8 -8 -4.5 0 3.2 9 9 9 9];
                    slider_ed(app,false);
                case "Jazz"
                    slider_val(app,[-2 5 -8 0 8 7 6 0 0 0]);
                    app.gains = [-2 5 -8 0 8 7 6 0 0 0];
                    slider_ed(app,false);
                case "Bass"
                    slider_val(app,[5 3.9 3.8 2.2 1 0 0 0 0 0]);
                    app.gains = [5 3.9 3.8 2.2 1 0 0 0 0 0];
                    slider_ed(app,false);
                case "Eargasm"
                    slider_val(app,[3 6.2 9.2 6.8 6.2 5.1 7 4.2 11.2 7.2]);
                    app.gains = [3 6.2 9.2 6.8 6.2 5.1 7 4.2 11.2 7.2];
                    slider_ed(app,false);  
                case "Perfect"
                    slider_val(app,[3 6.2 9.2 6.8 6.2 5.1 7 9.4 11.2 7.2]);
                    app.gains = [3 6.2 9.2 6.8 6.2 5.1 7 9.4 11.2 7.2];
                    slider_ed(app,false); 
                case "Dance"
                    slider_val(app,[0 2.5 7.4 0 2 5.2 6.8 4.9 5.1 -0.2]);
                    app.gains = [0 2.5 7.4 0 2 5.2 6.8 4.9 5.1 -0.2];
                    slider_ed(app,false);
                case "Live"
                    slider_val(app,[-3.6 0 4.2 5.3 5.9 5.3 2.5 2 1 0.1]);
                    app.gains = [-3.6 0 4.2 5.3 5.9 5.3 2.5 2 1 0.1];
                    slider_ed(app,false); 
                case "Club"
                    slider_val(app,[0 0 2 5 5 5 2 0 0 0]);
                    app.gains = [0 0 2 5 5 5 2 0 0 0];
                    slider_ed(app,false); 
                case "Powerful"
                    slider_val(app,[8 7.9 4.4 -4.4 -2.6 1.5 6.8 7.9 9.1 9.7]);
                    app.gains = [8 7.9 4.4 -4.4 -2.6 1.5 6.8 7.9 9.1 9.7];
                    slider_ed(app,false);    
                case "Soft"
                    slider_val(app,[2 0 -1.3 -3.5 -1.3 1.9 4 5.8 6.8 7.5]);
                    app.gains = [2 0 -1.3 -3.5 -1.3 1.9 4 5.8 6.8 7.5];
                    slider_ed(app,false); 
                case "Party"
                    slider_val(app,[5.5 5.5 0 0 0 0 0 0 5.5 5.5]);
                    app.gains = [5.5 5.5 0 0 0 0 0 0 5.5 5.5];
                    slider_ed(app,false);    
            end
            [app.equalizer_B, app.equalizer_A] = designParamEQ(app.order,app.gains,app.centerFreq,app.bandwidth);
            % Plot filter spectrum
            SOS = [app.equalizer_B',[ones(sum(app.order)/2,1),app.equalizer_A']];
            [H,~] = freqz(SOS);
            set(app.p5,'YData',20*log10(abs(H)));
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.VolumeSlider.Value = changingValue;
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            value = round(app.SpeedSlider.Value,1);
            app.SpeedSlider.Value = value;
            app.xLabel.Text = strcat(string(value),'x');
            app.frameLength = ceil(0.1*value*app.sampleRate);
            stop(app.player_seek);
            release(app.fileReader);            
            release(app.deviceWriter);
            app.fileReader = dsp.AudioFileReader(app.FilePathEditField.Value,'SamplesPerFrame',app.frameLength,'ReadRange',[app.cur_sample inf]);
            app.deviceWriter = audioDeviceWriter('SampleRate',ceil(value*app.sampleRate),'SupportVariableSizeInput',true);
            % Reduce Computational Load
            setup(app.deviceWriter,ones(app.frameLength,2));
            setup(app.fileReader);
            % Resume playback always
            if app.status == 1
                start(app.player_seek);
            end
        end

        % Callback function
        function VocoderSliderValueChanged(app, event)
            value = round(app.VocoderSlider.Value,1);
            app.VocoderSlider.Value = value;
            app.xLabel_2.Text = strcat(string(value),'x');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            % Calculate new current sample
            app.cur_sample = floor(value*app.total_samples/app.total_duration);
            if app.cur_sample == 0
                app.cur_sample = 1;
            end
            stop(app.player_seek);
            release(app.fileReader);            
            app.fileReader = dsp.AudioFileReader(app.FilePathEditField.Value,'SamplesPerFrame',app.frameLength,'ReadRange',[app.cur_sample inf]);
            cur_value = floor(app.cur_sample*ceil(app.total_duration)/app.total_samples);
            update_bar(app,cur_value,0);
            % Reduce Computational Load
            setup(app.fileReader);
            % Resume playback always
            if app.status == 1
                start(app.player_seek);
            end
        end

        % Close request function: EqualizerEXUIFigure
        function EqualizerEXUIFigureCloseRequest(app, event)
            delete(app.t_path);
            delete("info.mat");
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EqualizerEXUIFigure = uifigure('Visible', 'off');
            app.EqualizerEXUIFigure.Name = 'EqualizerEX';
            app.EqualizerEXUIFigure.CloseRequestFcn = createCallbackFcn(app, @EqualizerEXUIFigureCloseRequest, true);
            app.BrowseButton = uibutton(app.EqualizerEXUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'baseline_folder_open_black_18dp.png';
            app.BrowseButton.Text = 'Browse';
            app.FilePathEditFieldLabel = uilabel(app.EqualizerEXUIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.EqualizerEXUIFigure, 'text');
            app.FilePathEditField.Editable = 'off';
            app.TrackNameLabel = uilabel(app.EqualizerEXUIFigure);
            app.TrackNameLabel.Text = 'Track Name';
            app.SampleRateLabel = uilabel(app.EqualizerEXUIFigure);
            app.SampleRateLabel.Text = 'Sample Rate';
            app.Slider = uislider(app.EqualizerEXUIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Label = uilabel(app.EqualizerEXUIFigure);
            app.Label.Text = '00:00';
            app.Label_3 = uilabel(app.EqualizerEXUIFigure);
            app.Label_3.Text = '00:00';
            app.ArtistLabel = uilabel(app.EqualizerEXUIFigure);
            app.ArtistLabel.Text = 'Artist';
            app.VolumeSliderLabel = uilabel(app.EqualizerEXUIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.EqualizerEXUIFigure);
            app.VolumeSlider.MajorTicks = [];
            app.VolumeSlider.MajorTickLabels = {};
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.MinorTicks = [];
            app.VolumeSlider.Value = 40;
            app.PlayButton = uibutton(app.EqualizerEXUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'baseline_play_circle_outline_black_18dp.png';
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.EqualizerEXUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'baseline_pause_circle_outline_black_18dp.png';
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.EqualizerEXUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'baseline_stop_black_18dp.png';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.EQUALIZERPanel = uipanel(app.EqualizerEXUIFigure);
            app.EQUALIZERPanel.Title = '  EQUALIZER';
            app.Slider_10 = uislider(app.EQUALIZERPanel);
            app.Slider_10.Limits = [-12 12];
            app.Slider_10.MajorTickLabels = {''};
            app.Slider_10.ValueChangingFcn = createCallbackFcn(app, @Slider_10ValueChanging, true);
            app.Slider_9 = uislider(app.EQUALIZERPanel);
            app.Slider_9.Limits = [-12 12];
            app.Slider_9.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_9.ValueChangingFcn = createCallbackFcn(app, @Slider_9ValueChanging, true);
            app.Slider_8 = uislider(app.EQUALIZERPanel);
            app.Slider_8.Limits = [-12 12];
            app.Slider_8.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_8.ValueChangingFcn = createCallbackFcn(app, @Slider_8ValueChanging, true);
            app.Slider_7 = uislider(app.EQUALIZERPanel);
            app.Slider_7.Limits = [-12 12];
            app.Slider_7.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_7.ValueChangingFcn = createCallbackFcn(app, @Slider_7ValueChanging, true);
            app.Slider_6 = uislider(app.EQUALIZERPanel);
            app.Slider_6.Limits = [-12 12];
            app.Slider_6.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.Slider_5 = uislider(app.EQUALIZERPanel);
            app.Slider_5.Limits = [-12 12];
            app.Slider_5.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.Slider_4 = uislider(app.EQUALIZERPanel);
            app.Slider_4.Limits = [-12 12];
            app.Slider_4.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Slider_3 = uislider(app.EQUALIZERPanel);
            app.Slider_3.Limits = [-12 12];
            app.Slider_3.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_2 = uislider(app.EQUALIZERPanel);
            app.Slider_2.Limits = [-12 12];
            app.Slider_2.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_1 = uislider(app.EQUALIZERPanel);
            app.Slider_1.Limits = [-12 12];
            app.Slider_1.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.Slider_1.ValueChangingFcn = createCallbackFcn(app, @Slider_1ValueChanging, true);
            app.HzLabel = uilabel(app.EQUALIZERPanel);
            app.HzLabel.Text = '31 Hz';
            app.HzLabel_2 = uilabel(app.EQUALIZERPanel);
            app.HzLabel_2.Text = '62 Hz';
            app.HzLabel_3 = uilabel(app.EQUALIZERPanel);
            app.HzLabel_3.Text = '125 Hz';
            app.HzLabel_4 = uilabel(app.EQUALIZERPanel);
            app.HzLabel_4.Text = '250 Hz';
            app.HzLabel_5 = uilabel(app.EQUALIZERPanel);
            app.HzLabel_5.Text = '500 Hz';
            app.kHzLabel_2 = uilabel(app.EQUALIZERPanel);
            app.kHzLabel_2.Text = '1 kHz';
            app.kHzLabel_3 = uilabel(app.EQUALIZERPanel);
            app.kHzLabel_3.Text = '2 kHz';
            app.kHzLabel_4 = uilabel(app.EQUALIZERPanel);
            app.kHzLabel_4.Text = '4 kHz';
            app.kHzLabel_5 = uilabel(app.EQUALIZERPanel);
            app.kHzLabel_5.Text = '8 kHz';
            app.kHzLabel_6 = uilabel(app.EQUALIZERPanel);
            app.kHzLabel_6.Text = '16 kHz';
            app.Label_5 = uilabel(app.EQUALIZERPanel);
            app.Label_5.Text = '12';
            app.Label_6 = uilabel(app.EQUALIZERPanel);
            app.Label_6.Text = '-12';
            app.PRESETSButtonGroup = uibuttongroup(app.EqualizerEXUIFigure);
            app.PRESETSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PRESETSButtonGroupSelectionChanged, true);
            app.PRESETSButtonGroup.Title = '  PRESETS';
            app.ManualButton = uitogglebutton(app.PRESETSButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.PopButton = uitogglebutton(app.PRESETSButtonGroup);
            app.PopButton.Text = 'Pop';
            app.RockButton = uitogglebutton(app.PRESETSButtonGroup);
            app.RockButton.Text = 'Rock';
            app.VocalButton = uitogglebutton(app.PRESETSButtonGroup);
            app.VocalButton.Text = 'Vocal';
            app.TrebleButton = uitogglebutton(app.PRESETSButtonGroup);
            app.TrebleButton.Text = 'Treble';
            app.JazzButton = uitogglebutton(app.PRESETSButtonGroup);
            app.JazzButton.Text = 'Jazz';
            app.FlatButton_2 = uitogglebutton(app.PRESETSButtonGroup);
            app.FlatButton_2.Text = 'Flat';
            app.BassButton = uitogglebutton(app.PRESETSButtonGroup);
            app.BassButton.Text = 'Bass';
            app.LiveButton = uitogglebutton(app.PRESETSButtonGroup);
            app.LiveButton.Text = 'Live';
            app.ClubButton = uitogglebutton(app.PRESETSButtonGroup);
            app.ClubButton.Text = 'Club';
            app.PowerfulButton = uitogglebutton(app.PRESETSButtonGroup);
            app.PowerfulButton.Text = 'Powerful';
            app.DanceButton = uitogglebutton(app.PRESETSButtonGroup);
            app.DanceButton.Text = 'Dance';
            app.SoftButton = uitogglebutton(app.PRESETSButtonGroup);
            app.SoftButton.Text = 'Soft';
            app.PartyButton = uitogglebutton(app.PRESETSButtonGroup);
            app.PartyButton.Text = 'Party';
            app.PerfectButton = uitogglebutton(app.PRESETSButtonGroup);
            app.PerfectButton.Text = 'Perfect';
            app.EargasmButton = uitogglebutton(app.PRESETSButtonGroup);
            app.EargasmButton.Text = 'Eargasm';
            app.TabGroup = uitabgroup(app.EqualizerEXUIFigure);
            app.FrequencySpectrumTab = uitab(app.TabGroup);
            app.FrequencySpectrumTab.Title = 'Frequency Spectrum';
            app.UIAxes = uiaxes(app.FrequencySpectrumTab);
            xlabel(app.UIAxes, 'Frequencies (Hz)')
            ylabel(app.UIAxes, 'Normalized Magniitude')
            app.EqualizerFilterTab = uitab(app.TabGroup);
            app.EqualizerFilterTab.Title = 'Equalizer Filter';
            app.UIAxes_2 = uiaxes(app.EqualizerFilterTab);
            xlabel(app.UIAxes_2, 'Normalized Frequency')
            ylabel(app.UIAxes_2, 'Amplitude (dB)')
            app.TimePlotTab = uitab(app.TabGroup);
            app.TimePlotTab.Title = 'Time Plot';
            app.UIAxes_3 = uiaxes(app.TimePlotTab);
            xlabel(app.UIAxes_3, 'Samples')
            ylabel(app.UIAxes_3, 'Scaled Magnitude')
            app.MusicInfoTab = uitab(app.TabGroup);
            app.MusicInfoTab.Title = 'Music Info';
            app.TrackNameLabel_2 = uilabel(app.MusicInfoTab);
            app.TrackNameLabel_2.Text = 'Track Name';
            app.ArtistInformationLabel = uilabel(app.MusicInfoTab);
            app.ArtistInformationLabel.Text = 'Artist Information';
            app.AlbumLabel = uilabel(app.MusicInfoTab);
            app.AlbumLabel.Text = 'Album: ';
            app.DiscLabel = uilabel(app.MusicInfoTab);
            app.DiscLabel.Text = 'Disc:';
            app.GenreLabel = uilabel(app.MusicInfoTab);
            app.GenreLabel.Text = 'Genre:';
            app.TrackLabel = uilabel(app.MusicInfoTab);
            app.TrackLabel.Text = 'Track:';
            app.YearLabel = uilabel(app.MusicInfoTab);
            app.YearLabel.Text = 'Year:';
            app.DurationLabel = uilabel(app.MusicInfoTab);
            app.DurationLabel.Text = 'Duration:';
            app.FileSizeLabel = uilabel(app.MusicInfoTab);
            app.FileSizeLabel.Text = 'File Size:';
            app.Label_7 = uilabel(app.MusicInfoTab);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.MusicInfoTab);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.MusicInfoTab);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.MusicInfoTab);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.MusicInfoTab);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.MusicInfoTab);
            app.Label_12.Text = '';
            app.Label_13 = uilabel(app.MusicInfoTab);
            app.Label_13.Text = '';
            app.Image = uiimage(app.MusicInfoTab);
            app.PerksTab = uitab(app.TabGroup);
            app.PerksTab.Title = 'Perks';
            app.SpeedSliderLabel = uilabel(app.PerksTab);
            app.SpeedSliderLabel.Text = 'Speed ';
            app.SpeedSlider = uislider(app.PerksTab);
            app.SpeedSlider.Limits = [0.2 1.8];
            app.SpeedSlider.MajorTicks = [];
            app.SpeedSlider.MajorTickLabels = {};
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Enable = 'off';
            app.SpeedSlider.Value = 1;
            app.xLabel = uilabel(app.PerksTab);
            app.xLabel.Text = '1.0x';
            app.Label_4 = uilabel(app.PerksTab);
            app.Label_4.Text = 'Changing speed will reset the device writer (thus causing a small pause).';
            app.LyricsTab = uitab(app.TabGroup);
            app.LyricsTab.Title = 'Lyrics';
            app.lyrc = uilabel(app.LyricsTab);
            app.lyrc.Text = 'No .lrc file was found. Lyrics can''t be loaded up. Please check if the file exists';
            app.Switch = uiswitch(app.EqualizerEXUIFigure, 'slider');
            app.ShoworiginalresponseLabel = uilabel(app.EqualizerEXUIFigure);
            app.ShoworiginalresponseLabel.Text = 'Show original response';
            app.Switch_2 = uiswitch(app.EqualizerEXUIFigure, 'slider');
            app.ShowfilteredresponseLabel = uilabel(app.EqualizerEXUIFigure);
            app.ShowfilteredresponseLabel.Text = 'Show filtered response';
            % Show the figure after all components are created
            app.EqualizerEXUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EqualizerEXUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EqualizerEXUIFigure)
        end
    end
end